classdef ATC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ATCApplicationUIFigure       matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        StopButton                   matlab.ui.control.StateButton
        PauseButton                  matlab.ui.control.StateButton
        RunButton                    matlab.ui.control.Button
        ATCOptimizerLabel            matlab.ui.control.Label
        BetaSlider                   matlab.ui.control.Slider
        BetaSliderLabel              matlab.ui.control.Label
        GammaSlider                  matlab.ui.control.Slider
        GammaSliderLabel             matlab.ui.control.Label
        ConstraintFunctionEditField  matlab.ui.control.EditField
        ConstraintFunctionEditFieldLabel  matlab.ui.control.Label
        ObjectivefunctionEditField   matlab.ui.control.EditField
        ObjectivefunctionEditFieldLabel  matlab.ui.control.Label
        ToleranceEditField           matlab.ui.control.EditField
        ToleranceEditFieldLabel      matlab.ui.control.Label
        UpperboundEditField          matlab.ui.control.EditField
        UpperboundEditFieldLabel     matlab.ui.control.Label
        LowerboundEditField          matlab.ui.control.EditField
        LowerboundEditFieldLabel     matlab.ui.control.Label
        InitialguessEditField        matlab.ui.control.EditField
        InitialguessEditFieldLabel   matlab.ui.control.Label
        ConstraintspecificationEditField  matlab.ui.control.EditField
        ConstraintspecificationEditFieldLabel  matlab.ui.control.Label
        SubsystemwisevectordependanceEditField  matlab.ui.control.EditField
        SubsystemwisevectordependanceLabel  matlab.ui.control.Label
        ATCStructurearrayEditField   matlab.ui.control.EditField
        ATCStructurearrayEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function checkPauseStopStatus(app,flag)
            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            % If flag input is included and =1, buttons will be reset to de-selected.
            % if flag input is included and =2, all buttons will be disabled (used during file writing and other processes)
            % if flag input is included and =3, all buttons will be enabled (used during file writing and other processes)
            if nargin > 1
                switch flag
                    case 1 % reset pause/stop button
                        app.PauseButton.Value = false;
                        PauseButtonValueChanged(app, [])
                        app.StopButton.Value = false;
                    case 2 % disable buttons
                        app.PauseButton.Enable = 'off';
                        app.StopButton.Enable = 'off';
                    case 3 % enable buttons
                        app.PauseButton.Enable = 'on';
                        app.StopButton.Enable = 'on';
                    otherwise
                        error('Unknown flag value.')
                end
                drawnow()
            else
                if app.StopButton.Value
                    checkPauseStopStatus(app,1) % reset pause/stop buttons
                    error('MATLAB:eyeTrackAPP:abort','User aborted execution.')
                end
                if app.PauseButton.Value
                    waitfor(app.PauseButton,'Value',false)
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ATCStructurearrayEditField, 
        % ATCStructurearrayEditField
        function ATCStructurearrayEditFieldValueChanged(app, event)
            value = app.ATCStructurearrayEditField.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            eval("atc.struct="+app.ATCStructurearrayEditField.Value+";");
            eval("atc.sel_vec="+app.SubsystemwisevectordependanceEditField.Value+";");
            eval("atc.constr="+app.ConstraintspecificationEditField.Value+";");
            eval("atc.x0="+app.InitialguessEditField.Value+";");
            eval("atc.lb="+app.LowerboundEditField.Value+"';");
            eval("atc.ub="+app.UpperboundEditField.Value+"';");
            eval("atc.tol="+app.ToleranceEditField.Value+";");
            eval("atc.obj="+app.ObjectivefunctionEditField.Value+";");
            eval("atc.con="+app.ConstraintFunctionEditField.Value+";");
            atc.gamma =app.GammaSlider.Value;
            atc.beta=app.BetaSlider.Value;
            res=do_ATC(atc,app);
            save res;
        end

        % Value changed function: 
        % SubsystemwisevectordependanceEditField
        function SubsystemwisevectordependanceEditFieldValueChanged(app, event)
            value = app.SubsystemwisevectordependanceEditField.Value;
            
        end

        % Value changed function: ConstraintspecificationEditField
        function ConstraintspecificationEditFieldValueChanged(app, event)
            value = app.ConstraintspecificationEditField.Value;
            
        end

        % Value changed function: InitialguessEditField
        function InitialguessEditFieldValueChanged(app, event)
            value = app.InitialguessEditField.Value;
            
        end

        % Value changed function: LowerboundEditField
        function LowerboundEditFieldValueChanged(app, event)
            value = app.LowerboundEditField.Value;
        end

        % Callback function
        function PauseButtonPushed(app, event)
            if app.PauseButton.Text=='Pause'
                app.PauseButton.Text='Resume';
                pause
            elseif app.PauseButton.Text=='Resume'
                app.PauseButton.Text='Pause';
                pause(0.1)
            else
                pause
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            if app.PauseButton.Value
                app.StopButton.Enable = 'off';
                app.PauseButton.Text = 'Resume';
            else
                app.StopButton.Enable = 'on';
                app.PauseButton.Text = 'Pause';
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ATCApplicationUIFigure = uifigure('Visible', 'off');
            app.ATCApplicationUIFigure.Name = 'ATC Application';
            app.UIAxes = uiaxes(app.ATCApplicationUIFigure);
            title(app.UIAxes, 'Press "Run" to start optimization')
            xlabel(app.UIAxes, 'Iteration')
            ylabel(app.UIAxes, 'Objective/Constraint')
            app.ATCStructurearrayEditFieldLabel = uilabel(app.ATCApplicationUIFigure);
            app.ATCStructurearrayEditFieldLabel.Text = 'ATC Structure array';
            app.ATCStructurearrayEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.ATCStructurearrayEditField.ValueChangedFcn = createCallbackFcn(app, @ATCStructurearrayEditFieldValueChanged, true);
            app.ATCStructurearrayEditField.ValueChangingFcn = createCallbackFcn(app, @ATCStructurearrayEditFieldValueChanged, true);
            app.ATCStructurearrayEditField.Value = '{1,2,3,4}';
            app.SubsystemwisevectordependanceLabel = uilabel(app.ATCApplicationUIFigure);
            app.SubsystemwisevectordependanceLabel.Text = {'Subsystem wise     '; ' vector dependance'};
            app.SubsystemwisevectordependanceEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.SubsystemwisevectordependanceEditField.ValueChangedFcn = createCallbackFcn(app, @SubsystemwisevectordependanceEditFieldValueChanged, true);
            app.SubsystemwisevectordependanceEditField.Value = '[[1,1,1,1,1,1,1,1,1,1];[1,1,1,1,1,1,1,1,1,1];[1,1,1,1,1,1,1,1,1,1];[1,1,1,1,1,1,1,1,1,1]]';
            app.ConstraintspecificationEditFieldLabel = uilabel(app.ATCApplicationUIFigure);
            app.ConstraintspecificationEditFieldLabel.Text = 'Constraint specification';
            app.ConstraintspecificationEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.ConstraintspecificationEditField.ValueChangedFcn = createCallbackFcn(app, @ConstraintspecificationEditFieldValueChanged, true);
            app.ConstraintspecificationEditField.Value = '[0,1,1,1]';
            app.InitialguessEditFieldLabel = uilabel(app.ATCApplicationUIFigure);
            app.InitialguessEditFieldLabel.Text = 'Initial guess';
            app.InitialguessEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.InitialguessEditField.ValueChangedFcn = createCallbackFcn(app, @InitialguessEditFieldValueChanged, true);
            app.InitialguessEditField.Value = '[8, 80, 290, 300, 650,1,1,5000,0.9,16]';
            app.LowerboundEditFieldLabel = uilabel(app.ATCApplicationUIFigure);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.LowerboundEditField.ValueChangedFcn = createCallbackFcn(app, @LowerboundEditFieldValueChanged, true);
            app.LowerboundEditField.Value = '[0,10,50,20,100,1,1,2600,0.01,0]';
            app.UpperboundEditFieldLabel = uilabel(app.ATCApplicationUIFigure);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.UpperboundEditField.Value = '[10, 100, 999, 999, 9999,100,30,8800,1,200]';
            app.ToleranceEditFieldLabel = uilabel(app.ATCApplicationUIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.ToleranceEditField.Value = '0.15';
            app.ObjectivefunctionEditFieldLabel = uilabel(app.ATCApplicationUIFigure);
            app.ObjectivefunctionEditFieldLabel.Text = 'Objective function';
            app.ObjectivefunctionEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.ObjectivefunctionEditField.Value = '@objct';
            app.ConstraintFunctionEditFieldLabel = uilabel(app.ATCApplicationUIFigure);
            app.ConstraintFunctionEditFieldLabel.Text = 'Constraint Function';
            app.ConstraintFunctionEditField = uieditfield(app.ATCApplicationUIFigure, 'text');
            app.ConstraintFunctionEditField.Value = '@cons';
            app.GammaSliderLabel = uilabel(app.ATCApplicationUIFigure);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.ATCApplicationUIFigure);
            app.GammaSlider.Limits = [0 1];
            app.GammaSlider.Value = 0.35;
            app.BetaSliderLabel = uilabel(app.ATCApplicationUIFigure);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.ATCApplicationUIFigure);
            app.BetaSlider.Limits = [0 10];
            app.BetaSlider.Value = 4;
            app.ATCOptimizerLabel = uilabel(app.ATCApplicationUIFigure);
            app.ATCOptimizerLabel.Text = 'ATC Optimizer';
            app.RunButton = uibutton(app.ATCApplicationUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PauseButton = uibutton(app.ATCApplicationUIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.ATCApplicationUIFigure, 'state');
            app.StopButton.Text = 'Stop';
            app.TextArea = uitextarea(app.ATCApplicationUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Results will be shown here'};
            % Show the figure after all components are created
            app.ATCApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ATC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ATCApplicationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ATCApplicationUIFigure)
        end
    end
end